classdef SASD_BMCL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SleepapneascreeningdeviceUIFigure  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ScreeningTab                  matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        Switchmode                    matlab.ui.control.Switch
        SerialportDropDown            matlab.ui.control.DropDown
        SerialportLabel               matlab.ui.control.Label
        PleaseLabel                   matlab.ui.control.Label
        matLabel                      matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        BluetoothnameDropDown         matlab.ui.control.DropDown
        BluetoothnameLabel            matlab.ui.control.Label
        StartButton                   matlab.ui.control.StateButton
        NamefilematEditField          matlab.ui.control.EditField
        NamefilematLabel              matlab.ui.control.Label
        GridLayout5                   matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        Lamp                          matlab.ui.control.Lamp
        SCREENINGLabel                matlab.ui.control.Label
        DisplayTab                    matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        PeriodLabel                   matlab.ui.control.Label
        PSpinner                      matlab.ui.control.Spinner
        SecondLabel_3                 matlab.ui.control.Label
        DonottypemetLabel             matlab.ui.control.Label
        ShiftinitialtimeLabel         matlab.ui.control.Label
        SelectinitialtimetoshowLabel  matlab.ui.control.Label
        PreviousNextLabel             matlab.ui.control.Label
        SecondLabel                   matlab.ui.control.Label
        MinuteLabel                   matlab.ui.control.Label
        HourLabel                     matlab.ui.control.Label
        FilenamematLabel              matlab.ui.control.Label
        GridLayout6                   matlab.ui.container.GridLayout
        prevButton                    matlab.ui.control.StateButton
        nextButton                    matlab.ui.control.StateButton
        SSpinner                      matlab.ui.control.Spinner
        MSpinner                      matlab.ui.control.Spinner
        HSpinner                      matlab.ui.control.Spinner
        MONITORINGGRAPHLabel          matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        FileNameEditField             matlab.ui.control.EditField
        GridLayoutG1                  matlab.ui.container.GridLayout
        LabelDis1u                    matlab.ui.control.Label
        LabelDis1v                    matlab.ui.control.Label
        DropDownMode1                 matlab.ui.control.DropDown
        GridLayoutG2                  matlab.ui.container.GridLayout
        LabelDis2u                    matlab.ui.control.Label
        LabelDis2v                    matlab.ui.control.Label
        DropDownMode2                 matlab.ui.control.DropDown
        GridLayoutG3                  matlab.ui.container.GridLayout
        LabelDis3u                    matlab.ui.control.Label
        LabelDis3v                    matlab.ui.control.Label
        DropDownMode3                 matlab.ui.control.DropDown
        ShowButton                    matlab.ui.control.StateButton
        Image_2                       matlab.ui.control.Image
        g3                            matlab.ui.control.UIAxes
        g2                            matlab.ui.control.UIAxes
        g1                            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ShowButton
        function ShowButtonValueChanged(app, event)
            namefile = app.FileNameEditField.Value + ".mat";
            Millis = (app.SSpinner.Value + app.MSpinner.Value*60 + app.HSpinner.Value*3600)*1000;
            if app.DropDownMode1.Value == "PPG (IR)"
                M1 = 2;
            elseif app.DropDownMode1.Value == "PPG (Red)"
                M1 = 3;
            elseif app.DropDownMode1.Value == "ECG"
                M1 = 4;
            else
                M1 = 5;
            end
            if app.DropDownMode2.Value == "PPG (IR)"
                M2 = 2;
            elseif app.DropDownMode2.Value == "PPG (Red)"
                M2 = 3;
            elseif app.DropDownMode2.Value == "ECG"
                M2 = 4;
            else
                M2 = 5;
            end
            if app.DropDownMode3.Value == "PPG (IR)"
                M3 = 2;
            elseif app.DropDownMode3.Value == "PPG (Red)"
                M3 = 3;
            elseif app.DropDownMode3.Value == "ECG"
                M3 = 4;
            else
                M3 = 5;
            end
            
            if exist(namefile, 'file')>0
                Data = cell2mat(struct2cell(load(namefile)));
                if Millis+(app.PSpinner.Value*1000) <= max(Data(:,1))
                    %-------------------------------------
                    MakeDatair = [0;0];     %Set variable (this is table)
                    MakeDataird = [0;0];
                    MakeDatard = [0;0];
                    MakeDatardd = [0;0];
                    Dset = 30;              %Range moving average
                    datair = Data(:,2);
                    datard = Data(:,3);
                    for i = 1:numel(datair)
                        if i < numel(datair)-Dset
                            MakeDatair(i) = mean(datair(i:i+Dset));
                            MakeDatard(i) = mean(datard(i:i+Dset));
                        else
                            MakeDatair(i) = mean(datair(i-Dset:i));
                            MakeDatard(i) = mean(datard(i-Dset:i));
                        end
                    end
                    for i = 1:numel(datard)
                        if i < numel(datair)-Dset*10
                            MakeDataird(i) = mean(datair(i:i+Dset*10));
                            MakeDatardd(i) = mean(datard(i:i+Dset*10));
                        else
                            MakeDataird(i) = mean(datair(i-Dset*10:i));
                            MakeDatardd(i) = mean(datard(i-Dset*10:i));
                        end
                    end
                    Data(:,2) = MakeDatair-MakeDataird;
                    Data(:,3) = MakeDatard-MakeDatardd;
                    %-------------------------------------
                    rowi = find(Data(:,1)>Millis,1,"first");
                    rowf = find(Data(:,1)>(Millis+(app.PSpinner.Value*1000)),1,"first");
                    plot(app.g1,Data(rowi:rowf,1)/1000,Data(rowi:rowf,M1),"LineWidth",0.8,"Color",'c');
                    plot(app.g2,Data(rowi:rowf,1)/1000,Data(rowi:rowf,M2),"LineWidth",0.8,"Color",'c');
                    plot(app.g3,Data(rowi:rowf,1)/1000,Data(rowi:rowf,M3),"LineWidth",0.8,"Color",'c');
                    app.g1.XLim = [Data(rowi,1)/1000,Data(rowf,1)/1000];
                    app.g2.XLim = [Data(rowi,1)/1000,Data(rowf,1)/1000];
                    app.g3.XLim = [Data(rowi,1)/1000,Data(rowf,1)/1000];
                    %--------------------------------------
                    %---------------- BPM -----------------
                    Periode = app.PSpinner.Value*1000;
                    rowi = find(Data(:,1)>Millis,1,"first");
                    rowf = find(Data(:,1)>(Millis+Periode),1,"first");
                    heartwave = Data(rowi:rowf,4);
                    hw = (heartwave>(max(heartwave)+min(heartwave))/2);
                    for i=2:(rowf-rowi)
                        if hw(i) == hw(i-1)
                            hw(i-1) = 0;
                        end
                    end
                    rowti = find(hw,1,"first");
                    rowtf = find(hw,1,"last");
                    timenow = Data(rowi+rowtf,1) - Data(rowi+rowti,1);
                    BMP = round(sum(hw)/(timenow)*60000);
                    %--------------------------------------
                    %---------------- Breath --------------
                    Periode = app.PSpinner.Value*1000;
                    rowi = find(Data(:,1)>Millis,1,"first");
                    rowf = find(Data(:,1)>(Millis+Periode),1,"first");                    
                    breathwave = Data(rowi:rowf,5);
                    bw = (breathwave>(max(breathwave)+min(breathwave))/2);
                    for i=2:(rowf-rowi)
                        if bw(i) == bw(i-1)
                            bw(i-1) = 0;
                        end
                    end
                    rowti = find(bw,1,"first");
                    rowtf = find(bw,1,"last");
                    timenow = Data(rowi+rowtf,1) - Data(rowi+rowti,1);
                    RMP = round(sum(bw)/(timenow)*60000);
                    %--------------------------------------
                    %---------------- SpO2 ----------------
                    rowi = find(Data(:,1)>Millis,1,"first");
                    rowf = find(Data(:,1)>(Millis+Periode),1,"first"); 
                    irw = MakeDatair(rowi:rowf);
                    rdw = MakeDatard(rowi:rowf);
                    irac = max(irw)-min(irw); rdac = max(rdw)-min(rdw);
                    irdc = mean(irw); rddc = mean(rdw);
                    R = (rdac/rddc)/(irac/irdc);
                    if R<0.25
                        SpO2 = 100;
                    elseif R>=0.25 && R<0.3
                        SpO2 = 99;
                    elseif R>=0.3 && R<0.35
                        SpO2 = 98;
                    elseif R>=0.35 && R<0.4
                        SpO2 = 97;
                    elseif R>=0.4 && R<0.45
                        SpO2 = 96;
                    else
                        SpO2 = round(-58.081.*R + 119.8);
                    end
                    %--------------------------------------
                    %-------------- Display M -------------
                    app.LabelDis1v.Text = "--";
                    app.LabelDis2v.Text = "--";
                    app.LabelDis3v.Text = "--";
                    app.LabelDis1u.Text = "*";
                    app.LabelDis2u.Text = "*";
                    app.LabelDis3u.Text = "*";
                    if app.DropDownMode1.Value == "ECG"
                        app.LabelDis1v.Text = num2str(round(BMP));
                        app.LabelDis1u.Text = "BPM";
                    elseif app.DropDownMode2.Value == "ECG"
                        app.LabelDis2v.Text = num2str(round(BMP));
                        app.LabelDis2u.Text = "BPM";
                    elseif app.DropDownMode3.Value == "ECG"
                        app.LabelDis3v.Text = num2str(round(BMP));
                        app.LabelDis3u.Text = "BPM";
                    end
                    
                    if app.DropDownMode1.Value == "Breathe"
                        app.LabelDis1v.Text = num2str(round(RMP));
                        app.LabelDis1u.Text = "BrPM";
                    elseif app.DropDownMode2.Value == "Breathe"
                        app.LabelDis2v.Text = num2str(round(RMP));
                        app.LabelDis2u.Text = "BrPM";
                    elseif app.DropDownMode3.Value == "Breathe"
                        app.LabelDis3v.Text = num2str(round(RMP));
                        app.LabelDis3u.Text = "BrPM";
                    end
                    
                    if app.DropDownMode1.Value == "PPG (IR)"
                        app.LabelDis1v.Text = num2str(SpO2);
                        app.LabelDis1u.Text = "%O2";
                    elseif app.DropDownMode2.Value == "PPG (IR)"
                        app.LabelDis2v.Text = num2str(SpO2);
                        app.LabelDis2u.Text = "%O2";
                    elseif app.DropDownMode3.Value == "PPG (IR)"
                        app.LabelDis3v.Text = num2str(SpO2);
                        app.LabelDis3u.Text = "%O2";
                    end
                    
                    if app.DropDownMode1.Value == "PPG (Red)"
                        app.LabelDis1v.Text = num2str(R,'%.2f');
                        app.LabelDis1u.Text = "R:SpO2";
                    elseif app.DropDownMode2.Value == "PPG (Red)"
                        app.LabelDis2v.Text = num2str(R,'%.2f');
                        app.LabelDis2u.Text = "R:SpO2";
                    elseif app.DropDownMode3.Value == "PPG (Red)"
                        app.LabelDis3v.Text = num2str(R,'%.2f');
                        app.LabelDis3u.Text = "R:SpO2";
                    end
                    %--------------------------------------
                else
                    app.ShowButton.Text = "Try again";
                    app.ShowButton.BackgroundColor = 'r';
                    pause(1);
                    app.ShowButton.Text = "Show!";
                    app.ShowButton.BackgroundColor = "#0059FF";
                end
            else
                app.ShowButton.Text = "Try again";
                app.ShowButton.BackgroundColor = 'r';
                pause(1);
                app.ShowButton.Text = "Show!";
                app.ShowButton.BackgroundColor = "#0059FF";
            end
            app.ShowButton.Value = 0;
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            if app.Switchmode.Value == "Bluetooth mode"
                if isempty(app.NamefilematEditField.Value)
                    app.Lamp.Color = 'r';
                    app.StartButton.BackgroundColor = 'r';
                    app.StartButton.Text = "ERROR!";
                    app.StartButton.Value = 1;
                    app.PleaseLabel.Text = "Sorry, you should fill file name you want (do not type > .mat <).";
                    app.PleaseLabel.FontColor = 'r';
                    pause(1);
                    app.Lamp.Color = '#FF6929';
                    app.StartButton.BackgroundColor = '#FF6929';
                    app.StartButton.Text = "Start!";
                    app.StartButton.Value = 0;
                else
                    if (app.StartButton.Value == 1)
                        app.Lamp.Color = 'y';
                        app.StartButton.Text = "Wait";
                        app.StartButton.BackgroundColor = '#AFAFAF';
                        app.PleaseLabel.Text = "Do not press button again.";
                        app.PleaseLabel.FontColor = 'r';
                        pause(2); i=0;
                        b = bluetooth("78-E3-6D-11-FC-D6");
                        rounddata = ["NATSE";"BENZ"];
                        savedata = (1:8)*0;
                        app.Lamp.Color = '#0000A1';
                        while 1
                            if str2double(readline(b)) == 999
                                nSampling = str2double(readline(b));
                                app.PleaseLabel.Text = "Press button on device for stop record. (Do not press button on screen again)";
                                app.PleaseLabel.FontColor = '#0000A1';
                                app.StartButton.Text = "Recording..";
                                app.StartButton.BackgroundColor = '#0000A1';
                                while 1
                                    readdatanow = readline(b);
                                    if ~isempty(readdatanow)
                                        i = i+1;
                                        rounddata(i,1) = readdatanow;
                                    end
                                    if str2double(rounddata(i,1)) == 999
                                        break;
                                    end 
                                end   
                                namefile_round = app.NamefilematEditField.Value + "_round.mat";
                                namefile_div = app.NamefilematEditField.Value + "_div.mat";
                                save(namefile_round,'rounddata');
                                
                                app.PleaseLabel.Text = "Create file...";
                                app.PleaseLabel.FontColor = '#CCCC00';
                                app.StartButton.Text = "Save...";
                                app.StartButton.BackgroundColor = 'y';
                                
                                for i = 1:numel(rounddata(:,1))-1
                                    for j = 0:nSampling-1
                                        datai = char(rounddata(i,1));
                                        savedata(1+(i-1)*nSampling+j,1) = str2double(datai((1+(j*25)):(8+(j*25))));
                                        savedata(1+(i-1)*nSampling+j,2) = str2double(datai((9+(j*25)):(12+(j*25))));
                                        savedata(1+(i-1)*nSampling+j,3) = str2double(datai((13+(j*25)):(16+(j*25))));
                                        savedata(1+(i-1)*nSampling+j,4) = str2double(datai((17+(j*25)):(20+(j*25))));
                                        savedata(1+(i-1)*nSampling+j,5) = str2double(datai((21+(j*25)):(24+(j*25))));
                                        savedata(1+(i-1)*nSampling+j,6) = str2double(datai(25+(j*25)));
                                    end
                                end
                                
                                save(namefile_div,'savedata');
                                pause(2);
                                app.StartButton.Text = "Finish!";
                                app.StartButton.BackgroundColor = 'g';
                                app.PleaseLabel.Text = " ";
                                app.Lamp.Color = 'g';
                                pause(1);
                                app.Lamp.Color = '#FF6929';
                                app.StartButton.BackgroundColor = '#FF6929';
                                app.StartButton.Text = "Start!";
                                app.StartButton.Value = 0;
                                app.PleaseLabel.Text = "Press Start! button when you fill name file and select serial port do you connect with device.";
                                app.PleaseLabel.FontColor = '#FF6929';
                                break;
                            end
                        end
                    else
                        app.Lamp.Color = 'r';
                        app.StartButton.BackgroundColor = 'r';
                        app.StartButton.Text = "ERROR!";
                        app.StartButton.Value = 1;
                        app.PleaseLabel.Text = "Sorry, you made a mistake. Please turn on this program again and you should read notice";
                        app.PleaseLabel.FontColor = 'r';
                    end
                end 
            else
                if isempty(serialportlist("available"))
                    app.Lamp.Color = 'r';
                    app.StartButton.BackgroundColor = 'r';
                    app.StartButton.Text = "ERROR!";
                    app.StartButton.Value = 1;
                    app.PleaseLabel.Text = "Sorry, you should plug in serial port";
                    app.PleaseLabel.FontColor = 'r';
                    pause(1);
                    app.Lamp.Color = '#FF6929';
                    app.StartButton.BackgroundColor = '#FF6929';
                    app.StartButton.Text = "Start!";
                    app.StartButton.Value = 0;
                elseif isempty(app.NamefilematEditField.Value)
                    app.Lamp.Color = 'r';
                    app.StartButton.BackgroundColor = 'r';
                    app.StartButton.Text = "ERROR!";
                    app.StartButton.Value = 1;
                    app.PleaseLabel.Text = "Sorry, you should fill file name you want (do not type > .mat <).";
                    app.PleaseLabel.FontColor = 'r';
                    pause(1);
                    app.Lamp.Color = '#FF6929';
                    app.StartButton.BackgroundColor = '#FF6929';
                    app.StartButton.Text = "Start!";
                    app.StartButton.Value = 0;
                elseif ~(serialportlist("available")==app.SerialportDropDown.Value)
                    app.Lamp.Color = 'r';
                    app.StartButton.BackgroundColor = 'r';
                    app.StartButton.Text = "ERROR!";
                    app.StartButton.Value = 1;
                    app.PleaseLabel.Text = "Sorry, this serial is close.";
                    app.PleaseLabel.FontColor = 'r';
                    pause(1);
                    app.Lamp.Color = '#FF6929';
                    app.StartButton.BackgroundColor = '#FF6929';
                    app.StartButton.Text = "Start!";
                    app.StartButton.Value = 0;
                else
                    if (app.StartButton.Value == 1)
                        app.Lamp.Color = 'y';
                        app.StartButton.Text = "Wait";
                        app.StartButton.BackgroundColor = '#AFAFAF';
                        app.PleaseLabel.Text = "Do not press button again.";
                        app.PleaseLabel.FontColor = 'r';
                        pause(2);
                        COM = app.SerialportDropDown.Value;
                        delete(serialport(COM,115200));
                        s = serialport(COM,115200);
                        savedata = (1:8)*0; i=0;
                        rounddata = ["NATSE";"BENZ"];
                        app.Lamp.Color = '#0000A1';
                        while 1
                            if str2double(readline(s)) == 999
                                nSampling = str2double(readline(s));
                                app.PleaseLabel.Text = "Press button on device for stop record. (Do not press button on screen again)";
                                app.PleaseLabel.FontColor = '#0000A1';
                                app.StartButton.Text = "Recording..";
                                app.StartButton.BackgroundColor = '#0000A1';
                                while 1
                                    readdatanow = readline(s);
                                    if ~isempty(readdatanow)
                                        i = i+1;
                                        rounddata(i,1) = readdatanow;
                                    end
                                    if str2double(rounddata(i,1)) == 999
                                        break;
                                    end 
                                end   
                                namefile_round = app.NamefilematEditField.Value + "_round.mat";
                                namefile_div = app.NamefilematEditField.Value + "_div.mat";
                                save(namefile_round,'rounddata');
                                
                                app.PleaseLabel.Text = "Create file...";
                                app.PleaseLabel.FontColor = '#CCCC00';
                                app.StartButton.Text = "Save...";
                                app.StartButton.BackgroundColor = 'y';
                                
                                for i = 1:numel(rounddata(:,1))-1
                                    for j = 0:nSampling-1
                                        datai = char(rounddata(i,1));
                                        savedata(1+(i-1)*nSampling+j,1) = str2double(datai((1+(j*25)):(8+(j*25))));
                                        savedata(1+(i-1)*nSampling+j,2) = str2double(datai((9+(j*25)):(12+(j*25))));
                                        savedata(1+(i-1)*nSampling+j,3) = str2double(datai((13+(j*25)):(16+(j*25))));
                                        savedata(1+(i-1)*nSampling+j,4) = str2double(datai((17+(j*25)):(20+(j*25))));
                                        savedata(1+(i-1)*nSampling+j,5) = str2double(datai((21+(j*25)):(24+(j*25))));
                                        savedata(1+(i-1)*nSampling+j,6) = str2double(datai(25+(j*25)));
                                    end
                                end
                                
                                save(namefile_div,'savedata');
                                pause(2);
                                app.StartButton.Text = "Finish!";
                                app.StartButton.BackgroundColor = 'g';
                                app.PleaseLabel.Text = " ";
                                app.Lamp.Color = 'g';
                                pause(1);
                                app.Lamp.Color = '#FF6929';
                                app.StartButton.BackgroundColor = '#FF6929';
                                app.StartButton.Text = "Start!";
                                app.StartButton.Value = 0;
                                app.PleaseLabel.Text = "Press Start! button when you fill name file and select serial port do you connect with device.";
                                app.PleaseLabel.FontColor = '#FF6929';
                                break;
                            end
                        end
                    else
                        app.Lamp.Color = 'r';
                        app.StartButton.BackgroundColor = 'r';
                        app.StartButton.Text = "ERROR!";
                        app.StartButton.Value = 1;
                        app.PleaseLabel.Text = "Sorry, you made a mistake. Please turn on this program again and you should read notice";
                        app.PleaseLabel.FontColor = 'r';
                    end
                end
            end
        end

        % Value changed function: prevButton
        function prevButtonValueChanged(app, event)
            if app.SSpinner.Value < app.PSpinner.Value
                if app.MSpinner.Value < 1
                    if app.HSpinner.Value > 0
                        app.HSpinner.Value = app.HSpinner.Value - 1;
                        app.MSpinner.Value = 59;
                        app.SSpinner.Value = 60 - app.PSpinner.Value + app.SSpinner.Value;
                    else
                        app.prevButton.BackgroundColor = 'r';
                        pause(1);
                        app.prevButton.BackgroundColor = '#F0F0F0';
                    end
                else
                    app.MSpinner.Value = app.MSpinner.Value - 1;
                    app.SSpinner.Value = 60 - app.PSpinner.Value + app.SSpinner.Value;
                end
            else
                app.SSpinner.Value = app.SSpinner.Value - app.PSpinner.Value;
            end
            namefile = app.FileNameEditField.Value + ".mat";
            Millis = (app.SSpinner.Value + app.MSpinner.Value*60 + app.HSpinner.Value*3600)*1000;
            if app.DropDownMode1.Value == "PPG (IR)"
                M1 = 2;
            elseif app.DropDownMode1.Value == "PPG (Red)"
                M1 = 3;
            elseif app.DropDownMode1.Value == "ECG"
                M1 = 4;
            elseif app.DropDownMode1.Value == "Breathe"
                M1 = 5;
            elseif app.DropDownMode1.Value == "Snoring"
                M1 = 6;
            else
                M1 = 7;
            end
            if app.DropDownMode2.Value == "PPG (IR)"
                M2 = 2;
            elseif app.DropDownMode2.Value == "PPG (Red)"
                M2 = 3;
            elseif app.DropDownMode2.Value == "ECG"
                M2 = 4;
            elseif app.DropDownMode2.Value == "Breathe"
                M2 = 5;
            elseif app.DropDownMode2.Value == "Snoring"
                M2 = 6;
            else
                M2 = 7;
            end
            if app.DropDownMode3.Value == "PPG (IR)"
                M3 = 2;
            elseif app.DropDownMode3.Value == "PPG (Red)"
                M3 = 3;
            elseif app.DropDownMode3.Value == "ECG"
                M3 = 4;
            elseif app.DropDownMode3.Value == "Breathe"
                M3 = 5;
            elseif app.DropDownMode3.Value == "Snoring"
                M3 = 6;
            else
                M3 = 7;
            end
            if exist(namefile, 'file')>0
                Data = cell2mat(struct2cell(load(namefile)));
                if Millis+(app.PSpinner.Value*1000) < max(Data(:,1))
                    %-------------------------------------
                    MakeDatair = [0;0];     %Set variable (this is table)
                    MakeDataird = [0;0];
                    MakeDatard = [0;0];
                    MakeDatardd = [0;0];
                    Dset = 30;              %Range moving average
                    datair = Data(:,2);
                    datard = Data(:,3);
                    for i = 1:numel(datair)
                        if i < numel(datair)-Dset
                            MakeDatair(i) = mean(datair(i:i+Dset));
                            MakeDatard(i) = mean(datard(i:i+Dset));
                        else
                            MakeDatair(i) = mean(datair(i-Dset:i));
                            MakeDatard(i) = mean(datard(i-Dset:i));
                        end
                    end
                    for i = 1:numel(datard)
                        if i < numel(datair)-Dset*10
                            MakeDataird(i) = mean(datair(i:i+Dset*10));
                            MakeDatardd(i) = mean(datard(i:i+Dset*10));
                        else
                            MakeDataird(i) = mean(datair(i-Dset*10:i));
                            MakeDatardd(i) = mean(datard(i-Dset*10:i));
                        end
                    end
                    Data(:,2) = MakeDatair-MakeDataird;
                    Data(:,3) = MakeDatard-MakeDatardd;
                    %-------------------------------------
                    rowi = find(Data(:,1)>Millis,1,"first");
                    rowf = find(Data(:,1)>(Millis+(app.PSpinner.Value*1000)),1,"first");
                    plot(app.g1,Data(rowi:rowf,1)/1000,Data(rowi:rowf,M1),"LineWidth",0.8,"Color",'c');
                    plot(app.g2,Data(rowi:rowf,1)/1000,Data(rowi:rowf,M2),"LineWidth",0.8,"Color",'c');
                    plot(app.g3,Data(rowi:rowf,1)/1000,Data(rowi:rowf,M3),"LineWidth",0.8,"Color",'c');
                    app.g1.XLim = [Data(rowi,1)/1000,Data(rowf,1)/1000];
                    app.g2.XLim = [Data(rowi,1)/1000,Data(rowf,1)/1000];
                    app.g3.XLim = [Data(rowi,1)/1000,Data(rowf,1)/1000];
                    %--------------------------------------
                    %---------------- BPM -----------------
                    Periode = app.PSpinner.Value*1000;
                    rowi = find(Data(:,1)>Millis,1,"first");
                    rowf = find(Data(:,1)>(Millis+Periode),1,"first");
                    heartwave = Data(rowi:rowf,4);
                    hw = (heartwave>(max(heartwave)+min(heartwave))/2);
                    for i=2:(rowf-rowi)
                        if hw(i) == hw(i-1)
                            hw(i-1) = 0;
                        end
                    end
                    rowti = find(hw,1,"first");
                    rowtf = find(hw,1,"last");
                    timenow = Data(rowi+rowtf,1) - Data(rowi+rowti,1);
                    BMP = round(sum(hw)/(timenow)*60000);
                    %--------------------------------------
                    %---------------- Breath --------------
                    Periode = app.PSpinner.Value*1000;
                    rowi = find(Data(:,1)>Millis,1,"first");
                    rowf = find(Data(:,1)>(Millis+Periode),1,"first");                    
                    breathwave = Data(rowi:rowf,5);
                    bw = (breathwave>(max(breathwave)+min(breathwave))/2);
                    for i=2:(rowf-rowi)
                        if bw(i) == bw(i-1)
                            bw(i-1) = 0;
                        end
                    end
                    rowti = find(bw,1,"first");
                    rowtf = find(bw,1,"last");
                    timenow = Data(rowi+rowtf,1) - Data(rowi+rowti,1);
                    RMP = round(sum(bw)/(timenow)*60000);
                    %--------------------------------------
                    %---------------- SpO2 ----------------
                    rowi = find(Data(:,1)>Millis,1,"first");
                    rowf = find(Data(:,1)>(Millis+Periode),1,"first"); 
                    irw = MakeDatair(rowi:rowf);
                    rdw = MakeDatard(rowi:rowf);
                    irac = max(irw)-min(irw); rdac = max(rdw)-min(rdw);
                    irdc = mean(irw); rddc = mean(rdw);
                    R = (rdac/rddc)/(irac/irdc);
                    if R<0.25
                        SpO2 = 100;
                    elseif R>=0.25 && R<0.3
                        SpO2 = 99;
                    elseif R>=0.3 && R<0.35
                        SpO2 = 98;
                    elseif R>=0.35 && R<0.4
                        SpO2 = 97;
                    elseif R>=0.4 && R<0.45
                        SpO2 = 96;
                    else
                        SpO2 = round(-58.081.*R + 119.8);
                    end
                    %--------------------------------------
                    %-------------- Display M -------------
                    app.LabelDis1v.Text = "--";
                    app.LabelDis2v.Text = "--";
                    app.LabelDis3v.Text = "--";
                    app.LabelDis1u.Text = "*";
                    app.LabelDis2u.Text = "*";
                    app.LabelDis3u.Text = "*";
                    if app.DropDownMode1.Value == "ECG"
                        app.LabelDis1v.Text = num2str(round(BMP));
                        app.LabelDis1u.Text = "BPM";
                    elseif app.DropDownMode2.Value == "ECG"
                        app.LabelDis2v.Text = num2str(round(BMP));
                        app.LabelDis2u.Text = "BPM";
                    elseif app.DropDownMode3.Value == "ECG"
                        app.LabelDis3v.Text = num2str(round(BMP));
                        app.LabelDis3u.Text = "BPM";
                    end
                    
                    if app.DropDownMode1.Value == "Breathe"
                        app.LabelDis1v.Text = num2str(round(RMP));
                        app.LabelDis1u.Text = "BrPM";
                    elseif app.DropDownMode2.Value == "Breathe"
                        app.LabelDis2v.Text = num2str(round(RMP));
                        app.LabelDis2u.Text = "BrPM";
                    elseif app.DropDownMode3.Value == "Breathe"
                        app.LabelDis3v.Text = num2str(round(RMP));
                        app.LabelDis3u.Text = "BrPM";
                    end
                    
                    if app.DropDownMode1.Value == "PPG (IR)"
                        app.LabelDis1v.Text = num2str(SpO2);
                        app.LabelDis1u.Text = "%O2";
                    elseif app.DropDownMode2.Value == "PPG (IR)"
                        app.LabelDis2v.Text = num2str(SpO2);
                        app.LabelDis2u.Text = "%O2";
                    elseif app.DropDownMode3.Value == "PPG (IR)"
                        app.LabelDis3v.Text = num2str(SpO2);
                        app.LabelDis3u.Text = "%O2";
                    end
                    
                    if app.DropDownMode1.Value == "PPG (Red)"
                        app.LabelDis1v.Text = num2str(R,'%.2f');
                        app.LabelDis1u.Text = "R:SpO2";
                    elseif app.DropDownMode2.Value == "PPG (Red)"
                        app.LabelDis2v.Text = num2str(R,'%.2f');
                        app.LabelDis2u.Text = "R:SpO2";
                    elseif app.DropDownMode3.Value == "PPG (Red)"
                        app.LabelDis3v.Text = num2str(R,'%.2f');
                        app.LabelDis3u.Text = "R:SpO2";
                    end
                    %--------------------------------------
                else
                    app.ShowButton.BackgroundColor = 'r';
                    app.prevButton.BackgroundColor = 'r';
                    pause(1);
                    app.prevButton.BackgroundColor = '#F0F0F0';
                end
            else
                app.ShowButton.BackgroundColor = 'r';
                app.prevButton.BackgroundColor = 'r';
                pause(1);
                app.prevButton.BackgroundColor = '#F0F0F0';
            end
            app.prevButton.Value = 0;
        end

        % Value changed function: nextButton
        function nextButtonValueChanged(app, event)
            if app.SSpinner.Value >= (60 - app.PSpinner.Value)
                if app.MSpinner.Value == 59
                    if app.HSpinner.Value < 10
                        app.HSpinner.Value = app.HSpinner.Value + 1;
                        app.MSpinner.Value = 0;
                        app.SSpinner.Value = 0;
                    else
                        app.nextButton.BackgroundColor = 'r';
                        pause(1);
                        app.nextButton.BackgroundColor = '#0059FF';
                    end
                else
                    app.MSpinner.Value = app.MSpinner.Value + 1;
                    app.SSpinner.Value = 0;
                end
            else
                app.SSpinner.Value = app.SSpinner.Value + app.PSpinner.Value;
            end
            namefile = app.FileNameEditField.Value + ".mat";
            Millis = (app.SSpinner.Value + app.MSpinner.Value*60 + app.HSpinner.Value*3600)*1000;
            if app.DropDownMode1.Value == "PPG (IR)"
                M1 = 2;
            elseif app.DropDownMode1.Value == "PPG (Red)"
                M1 = 3;
            elseif app.DropDownMode1.Value == "ECG"
                M1 = 4;
            elseif app.DropDownMode1.Value == "Breathe"
                M1 = 5;
            elseif app.DropDownMode1.Value == "Snoring"
                M1 = 6;
            else
                M1 = 7;
            end
            if app.DropDownMode2.Value == "PPG (IR)"
                M2 = 2;
            elseif app.DropDownMode2.Value == "PPG (Red)"
                M2 = 3;
            elseif app.DropDownMode2.Value == "ECG"
                M2 = 4;
            elseif app.DropDownMode2.Value == "Breathe"
                M2 = 5;
            elseif app.DropDownMode2.Value == "Snoring"
                M2 = 6;
            else
                M2 = 7;
            end
            if app.DropDownMode3.Value == "PPG (IR)"
                M3 = 2;
            elseif app.DropDownMode3.Value == "PPG (Red)"
                M3 = 3;
            elseif app.DropDownMode3.Value == "ECG"
                M3 = 4;
            elseif app.DropDownMode3.Value == "Breathe"
                M3 = 5;
            elseif app.DropDownMode3.Value == "Snoring"
                M3 = 6;
            else
                M3 = 7;
            end
            if exist(namefile, 'file')>0
                Data = cell2mat(struct2cell(load(namefile)));
                if Millis+(app.PSpinner.Value*1000) < max(Data(:,1))
                    %-------------------------------------
                    MakeDatair = [0;0];     %Set variable (this is table)
                    MakeDataird = [0;0];
                    MakeDatard = [0;0];
                    MakeDatardd = [0;0];
                    Dset = 30;              %Range moving average
                    datair = Data(:,2);
                    datard = Data(:,3);
                    for i = 1:numel(datair)
                        if i < numel(datair)-Dset
                            MakeDatair(i) = mean(datair(i:i+Dset));
                            MakeDatard(i) = mean(datard(i:i+Dset));
                        else
                            MakeDatair(i) = mean(datair(i-Dset:i));
                            MakeDatard(i) = mean(datard(i-Dset:i));
                        end
                    end
                    for i = 1:numel(datard)
                        if i < numel(datair)-Dset*10
                            MakeDataird(i) = mean(datair(i:i+Dset*10));
                            MakeDatardd(i) = mean(datard(i:i+Dset*10));
                        else
                            MakeDataird(i) = mean(datair(i-Dset*10:i));
                            MakeDatardd(i) = mean(datard(i-Dset*10:i));
                        end
                    end
                    Data(:,2) = MakeDatair-MakeDataird;
                    Data(:,3) = MakeDatard-MakeDatardd;
                    %-------------------------------------
                    rowi = find(Data(:,1)>Millis,1,"first");
                    rowf = find(Data(:,1)>(Millis+(app.PSpinner.Value*1000)),1,"first");
                    plot(app.g1,Data(rowi:rowf,1)/1000,Data(rowi:rowf,M1),"LineWidth",0.8,"Color",'c');
                    plot(app.g2,Data(rowi:rowf,1)/1000,Data(rowi:rowf,M2),"LineWidth",0.8,"Color",'c');
                    plot(app.g3,Data(rowi:rowf,1)/1000,Data(rowi:rowf,M3),"LineWidth",0.8,"Color",'c');
                    app.g1.XLim = [Data(rowi,1)/1000,Data(rowf,1)/1000];
                    app.g2.XLim = [Data(rowi,1)/1000,Data(rowf,1)/1000];
                    app.g3.XLim = [Data(rowi,1)/1000,Data(rowf,1)/1000];
                    %--------------------------------------
                    %---------------- BPM -----------------
                    Periode = app.PSpinner.Value*1000;
                    rowi = find(Data(:,1)>Millis,1,"first");
                    rowf = find(Data(:,1)>(Millis+Periode),1,"first");
                    heartwave = Data(rowi:rowf,4);
                    hw = (heartwave>(max(heartwave)+min(heartwave))/2);
                    for i=2:(rowf-rowi)
                        if hw(i) == hw(i-1)
                            hw(i-1) = 0;
                        end
                    end
                    rowti = find(hw,1,"first");
                    rowtf = find(hw,1,"last");
                    timenow = Data(rowi+rowtf,1) - Data(rowi+rowti,1);
                    BMP = round(sum(hw)/(timenow)*60000);
                    %--------------------------------------
                    %---------------- Breath --------------
                    Periode = app.PSpinner.Value*1000;
                    rowi = find(Data(:,1)>Millis,1,"first");
                    rowf = find(Data(:,1)>(Millis+Periode),1,"first");                    
                    breathwave = Data(rowi:rowf,5);
                    bw = (breathwave>(max(breathwave)+min(breathwave))/2);
                    for i=2:(rowf-rowi)
                        if bw(i) == bw(i-1)
                            bw(i-1) = 0;
                        end
                    end
                    rowti = find(bw,1,"first");
                    rowtf = find(bw,1,"last");
                    timenow = Data(rowi+rowtf,1) - Data(rowi+rowti,1);
                    RMP = round(sum(bw)/(timenow)*60000);
                    %--------------------------------------
                    %---------------- SpO2 ----------------
                    rowi = find(Data(:,1)>Millis,1,"first");
                    rowf = find(Data(:,1)>(Millis+Periode),1,"first"); 
                    irw = MakeDatair(rowi:rowf);
                    rdw = MakeDatard(rowi:rowf);
                    irac = max(irw)-min(irw); rdac = max(rdw)-min(rdw);
                    irdc = mean(irw); rddc = mean(rdw);
                    R = (rdac/rddc)/(irac/irdc);
                    if R<0.25
                        SpO2 = 100;
                    elseif R>=0.25 && R<0.3
                        SpO2 = 99;
                    elseif R>=0.3 && R<0.35
                        SpO2 = 98;
                    elseif R>=0.35 && R<0.4
                        SpO2 = 97;
                    elseif R>=0.4 && R<0.45
                        SpO2 = 96;
                    else
                        SpO2 = round(-58.081.*R + 119.8);
                    end
                    %--------------------------------------
                    %-------------- Display M -------------
                    app.LabelDis1v.Text = "--";
                    app.LabelDis2v.Text = "--";
                    app.LabelDis3v.Text = "--";
                    app.LabelDis1u.Text = "*";
                    app.LabelDis2u.Text = "*";
                    app.LabelDis3u.Text = "*";
                    if app.DropDownMode1.Value == "ECG"
                        app.LabelDis1v.Text = num2str(round(BMP));
                        app.LabelDis1u.Text = "BPM";
                    elseif app.DropDownMode2.Value == "ECG"
                        app.LabelDis2v.Text = num2str(round(BMP));
                        app.LabelDis2u.Text = "BPM";
                    elseif app.DropDownMode3.Value == "ECG"
                        app.LabelDis3v.Text = num2str(round(BMP));
                        app.LabelDis3u.Text = "BPM";
                    end
                    
                    if app.DropDownMode1.Value == "Breathe"
                        app.LabelDis1v.Text = num2str(round(RMP));
                        app.LabelDis1u.Text = "BrPM";
                    elseif app.DropDownMode2.Value == "Breathe"
                        app.LabelDis2v.Text = num2str(round(RMP));
                        app.LabelDis2u.Text = "BrPM";
                    elseif app.DropDownMode3.Value == "Breathe"
                        app.LabelDis3v.Text = num2str(round(RMP));
                        app.LabelDis3u.Text = "BrPM";
                    end
                    
                    if app.DropDownMode1.Value == "PPG (IR)"
                        app.LabelDis1v.Text = num2str(SpO2);
                        app.LabelDis1u.Text = "%O2";
                    elseif app.DropDownMode2.Value == "PPG (IR)"
                        app.LabelDis2v.Text = num2str(SpO2);
                        app.LabelDis2u.Text = "%O2";
                    elseif app.DropDownMode3.Value == "PPG (IR)"
                        app.LabelDis3v.Text = num2str(SpO2);
                        app.LabelDis3u.Text = "%O2";
                    end
                    
                    if app.DropDownMode1.Value == "PPG (Red)"
                        app.LabelDis1v.Text = num2str(R,'%.2f');
                        app.LabelDis1u.Text = "R:SpO2";
                    elseif app.DropDownMode2.Value == "PPG (Red)"
                        app.LabelDis2v.Text = num2str(R,'%.2f');
                        app.LabelDis2u.Text = "R:SpO2";
                    elseif app.DropDownMode3.Value == "PPG (Red)"
                        app.LabelDis3v.Text = num2str(R,'%.2f');
                        app.LabelDis3u.Text = "R:SpO2";
                    end
                    %--------------------------------------
                else
                    app.ShowButton.BackgroundColor = 'r';
                    app.nextButton.BackgroundColor = 'r';
                    pause(1);
                    app.nextButton.BackgroundColor = '#0059FF';
                end
            else
                app.ShowButton.BackgroundColor = 'r';
                app.nextButton.BackgroundColor = 'r';
                pause(1);
                app.nextButton.BackgroundColor = '#0059FF';
            end
            app.nextButton.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SleepapneascreeningdeviceUIFigure = uifigure('Visible', 'off');
            app.SleepapneascreeningdeviceUIFigure.Name = 'Sleep apnea screening device';
            app.SleepapneascreeningdeviceUIFigure.Icon = 'BMCL-sq.png';
            app.TabGroup = uitabgroup(app.SleepapneascreeningdeviceUIFigure);
            app.ScreeningTab = uitab(app.TabGroup);
            app.ScreeningTab.Title = 'Screening';
            app.GridLayout = uigridlayout(app.ScreeningTab);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.SCREENINGLabel = uilabel(app.GridLayout5);
            app.SCREENINGLabel.Text = 'SCREENING';
            app.Lamp = uilamp(app.GridLayout5);
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = 'kmitl_logo.png';
            app.NamefilematLabel = uilabel(app.GridLayout);
            app.NamefilematLabel.Text = 'Name file (.mat) :';
            app.NamefilematEditField = uieditfield(app.GridLayout, 'text');
            app.StartButton = uibutton(app.GridLayout, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start!';
            app.BluetoothnameLabel = uilabel(app.GridLayout);
            app.BluetoothnameLabel.Text = 'Bluetooth name :';
            app.BluetoothnameDropDown = uidropdown(app.GridLayout);
            app.BluetoothnameDropDown.Items = {'BMCL', 'NATSE', 'ESP32...'};
            app.BluetoothnameDropDown.Value = 'BMCL';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = 'BMCL-logo.png';
            app.matLabel = uilabel(app.GridLayout);
            app.matLabel.Text = '.mat';
            app.PleaseLabel = uilabel(app.GridLayout);
            app.PleaseLabel.Text = 'Press Start! button when you fill name file and select serial port do you connect with device.';
            app.SerialportLabel = uilabel(app.GridLayout);
            app.SerialportLabel.Text = 'Serial port :';
            app.SerialportDropDown = uidropdown(app.GridLayout);
            app.SerialportDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.SerialportDropDown.Value = 'COM1';
            app.Switchmode = uiswitch(app.GridLayout, 'slider');
            app.Switchmode.Items = {'Serial mode', 'Bluetooth mode'};
            app.Switchmode.Value = 'Serial mode';
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.GridLayout2 = uigridlayout(app.DisplayTab);
            app.g1 = uiaxes(app.GridLayout2);
            app.g2 = uiaxes(app.GridLayout2);
            app.g3 = uiaxes(app.GridLayout2);
            app.Image_2 = uiimage(app.GridLayout2);
            app.Image_2.ImageSource = 'kmitl_logo - White.png';
            app.ShowButton = uibutton(app.GridLayout2, 'state');
            app.ShowButton.ValueChangedFcn = createCallbackFcn(app, @ShowButtonValueChanged, true);
            app.ShowButton.Text = 'Show!';
            app.GridLayoutG3 = uigridlayout(app.GridLayout2);
            app.DropDownMode3 = uidropdown(app.GridLayoutG3);
            app.DropDownMode3.Items = {'ECG', 'PPG (IR)', 'PPG (Red)', 'Breathe', 'Snoring', 'Chest'};
            app.DropDownMode3.Value = 'Breathe';
            app.LabelDis3v = uilabel(app.GridLayoutG3);
            app.LabelDis3v.Text = '--';
            app.LabelDis3u = uilabel(app.GridLayoutG3);
            app.LabelDis3u.Text = '*';
            app.GridLayoutG2 = uigridlayout(app.GridLayout2);
            app.DropDownMode2 = uidropdown(app.GridLayoutG2);
            app.DropDownMode2.Items = {'ECG', 'PPG (IR)', 'PPG (Red)', 'Breathe', 'Snoring', 'Chest'};
            app.DropDownMode2.Value = 'PPG (IR)';
            app.LabelDis2v = uilabel(app.GridLayoutG2);
            app.LabelDis2v.Text = '--';
            app.LabelDis2u = uilabel(app.GridLayoutG2);
            app.LabelDis2u.Text = '*';
            app.GridLayoutG1 = uigridlayout(app.GridLayout2);
            app.DropDownMode1 = uidropdown(app.GridLayoutG1);
            app.DropDownMode1.Items = {'ECG', 'PPG (IR)', 'PPG (Red)', 'Breathe', 'Snoring', 'Chest'};
            app.DropDownMode1.Value = 'ECG';
            app.LabelDis1v = uilabel(app.GridLayoutG1);
            app.LabelDis1v.Text = '--';
            app.LabelDis1u = uilabel(app.GridLayoutG1);
            app.LabelDis1u.Text = '*';
            app.FileNameEditField = uieditfield(app.GridLayout2, 'text');
            app.Image3 = uiimage(app.GridLayout2);
            app.Image3.ImageSource = 'BMCL-white.png';
            app.MONITORINGGRAPHLabel = uilabel(app.GridLayout2);
            app.MONITORINGGRAPHLabel.Text = 'MONITORING GRAPH';
            app.HSpinner = uispinner(app.GridLayout2);
            app.HSpinner.Limits = [0 10];
            app.HSpinner.RoundFractionalValues = 'on';
            app.HSpinner.ValueDisplayFormat = '%.0f';
            app.MSpinner = uispinner(app.GridLayout2);
            app.MSpinner.Limits = [0 59];
            app.MSpinner.RoundFractionalValues = 'on';
            app.MSpinner.ValueDisplayFormat = '%.0f';
            app.SSpinner = uispinner(app.GridLayout2);
            app.SSpinner.Step = 5;
            app.SSpinner.Limits = [0 59];
            app.SSpinner.RoundFractionalValues = 'on';
            app.SSpinner.ValueDisplayFormat = '%.0f';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.nextButton = uibutton(app.GridLayout6, 'state');
            app.nextButton.ValueChangedFcn = createCallbackFcn(app, @nextButtonValueChanged, true);
            app.nextButton.Text = '>>';
            app.prevButton = uibutton(app.GridLayout6, 'state');
            app.prevButton.ValueChangedFcn = createCallbackFcn(app, @prevButtonValueChanged, true);
            app.prevButton.Text = '<<';
            app.FilenamematLabel = uilabel(app.GridLayout2);
            app.FilenamematLabel.Text = 'File name (.mat)';
            app.HourLabel = uilabel(app.GridLayout2);
            app.HourLabel.Text = 'Hour';
            app.MinuteLabel = uilabel(app.GridLayout2);
            app.MinuteLabel.Text = 'Minute';
            app.SecondLabel = uilabel(app.GridLayout2);
            app.SecondLabel.Text = 'Second';
            app.PreviousNextLabel = uilabel(app.GridLayout2);
            app.PreviousNextLabel.Text = 'Previous / Next';
            app.SelectinitialtimetoshowLabel = uilabel(app.GridLayout2);
            app.SelectinitialtimetoshowLabel.Text = 'Select initial time to show';
            app.ShiftinitialtimeLabel = uilabel(app.GridLayout2);
            app.ShiftinitialtimeLabel.Text = 'Shift initial time';
            app.DonottypemetLabel = uilabel(app.GridLayout2);
            app.DonottypemetLabel.Text = 'Do not type ".met"';
            app.SecondLabel_3 = uilabel(app.GridLayout2);
            app.SecondLabel_3.Text = 'Second';
            app.PSpinner = uispinner(app.GridLayout2);
            app.PSpinner.Limits = [1 10];
            app.PSpinner.RoundFractionalValues = 'on';
            app.PSpinner.ValueDisplayFormat = '%.0f';
            app.PSpinner.Value = 5;
            app.PeriodLabel = uilabel(app.GridLayout2);
            app.PeriodLabel.Text = 'Period';
            % Show the figure after all components are created
            app.SleepapneascreeningdeviceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SASD_BMCL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SleepapneascreeningdeviceUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SleepapneascreeningdeviceUIFigure)
        end
    end
end